classdef standardvoltage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WhichStandardVoltageButtonGroup  matlab.ui.container.ButtonGroup
        Button_9                       matlab.ui.control.RadioButton
        Button_8                       matlab.ui.control.RadioButton
        Button_7                       matlab.ui.control.RadioButton
        Button_6                       matlab.ui.control.RadioButton
        Button_5                       matlab.ui.control.RadioButton
        Button_4                       matlab.ui.control.RadioButton
        Button_3                       matlab.ui.control.RadioButton
        Button_2                       matlab.ui.control.RadioButton
        Button                         matlab.ui.control.RadioButton
        standardvoltagesTextArea       matlab.ui.control.TextArea
        standardvoltagesTextAreaLabel  matlab.ui.control.Label
    end

methods 
    function outputStr = standprinterv2(app)
    [stand1,stand2,stand3,stand4,stand5,stand6,stand7,stand8,stand9] = stored;
    
    outputStr = {};  % Initialize empty cell array for lines

    for i = 1:9
        eval(sprintf('stand = stand%d;', i));  % Dynamically get stand1 to stand9
        s = size(stand, 1);
        lineStr = sprintf('%d) %20', i);
        for k = 1:s
             lineStr = strcat(lineStr, sprintf('  %d ', stand(k)));
        end
        outputStr{end+1} = lineStr;  % Add line to cell array
    end
    end


end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.standardvoltagesTextArea.Value=standprinterv2(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.standardvoltagesTextAreaLabel = uilabel(app.UIFigure);
            app.standardvoltagesTextAreaLabel.Text = 'standard voltages';
            app.standardvoltagesTextArea = uitextarea(app.UIFigure);
            app.WhichStandardVoltageButtonGroup = uibuttongroup(app.UIFigure);
            app.WhichStandardVoltageButtonGroup.Title = 'Which Standard Voltage';
            app.Button = uiradiobutton(app.WhichStandardVoltageButtonGroup);
            app.Button.Text = '1';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.WhichStandardVoltageButtonGroup);
            app.Button_2.Text = '2';
            app.Button_3 = uiradiobutton(app.WhichStandardVoltageButtonGroup);
            app.Button_3.Text = '3';
            app.Button_4 = uiradiobutton(app.WhichStandardVoltageButtonGroup);
            app.Button_4.Text = '4';
            app.Button_5 = uiradiobutton(app.WhichStandardVoltageButtonGroup);
            app.Button_5.Text = '5';
            app.Button_6 = uiradiobutton(app.WhichStandardVoltageButtonGroup);
            app.Button_6.Text = '6';
            app.Button_7 = uiradiobutton(app.WhichStandardVoltageButtonGroup);
            app.Button_7.Text = '7';
            app.Button_8 = uiradiobutton(app.WhichStandardVoltageButtonGroup);
            app.Button_8.Text = '8';
            app.Button_9 = uiradiobutton(app.WhichStandardVoltageButtonGroup);
            app.Button_9.Text = '9';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = standardvoltage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end